classdef ExtractChannelsWaterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Step3Label                      matlab.ui.control.Label
        Step2Label                      matlab.ui.control.Label
        Step1Label                      matlab.ui.control.Label
        ExtractInternalConstraintsButton  matlab.ui.control.Button
        WriteADMESHinputfileButton      matlab.ui.control.Button
        SwitchLandWaterMaskButton       matlab.ui.control.Button
        CreateLandWaterMaskButton       matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        MinLengthmEditField             matlab.ui.control.NumericEditField
        MinLengthmEditFieldLabel        matlab.ui.control.Label
        MaxChannelWidthmEditField       matlab.ui.control.NumericEditField
        MaxChannelWidthmEditFieldLabel  matlab.ui.control.Label
        BackgroundGridResolmEditField   matlab.ui.control.NumericEditField
        BackgroundGridResolmEditFieldLabel  matlab.ui.control.Label
        MinAreaEditField                matlab.ui.control.NumericEditField
        MinAream2EditField_2Label       matlab.ui.control.Label
        AdvancedSettingsTab             matlab.ui.container.Tab
        GridLayout2_2                   matlab.ui.container.GridLayout
        PruningRhoEditField             matlab.ui.control.NumericEditField
        PruningRhoEditFieldLabel        matlab.ui.control.Label
        PruningThetaEditField           matlab.ui.control.NumericEditField
        PruningThetaEditFieldLabel      matlab.ui.control.Label
        NyDomainDecompEditField         matlab.ui.control.NumericEditField
        NyDomainDecompEditFieldLabel    matlab.ui.control.Label
        NxDomainDecompEditField         matlab.ui.control.NumericEditField
        NxDomainDecompEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        MainApp
        PTS
        xyzFun
        ElevationDataFilename
        UIAxes
        ProgressBarButton
        pgon
        pgon_ext

        Constraints
        pgon_old
        MA_1D_mainstream
        MA_1D_mainstreamLand
        W2D_boundaryID

        OutputSaved = 0;
        SavedFile
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
            app.UIAxes = mainapp.UIAxes;
            app.ProgressBarButton = mainapp.ProgressBarButton;
            app.ElevationDataFilename = mainapp.ElevationDataFilename;

            app.PTS = mainapp.PTS;
            app.xyzFun = mainapp.xyzFun;

            % if isfield(mainapp.subapps,'ChannelsWater')
            %     app.pgon = mainapp.subapps.ChannelsWater.pgon;
            %     app.pgon_ext = mainapp.subapps.ChannelsWater.pgon_ext;
            % end
            % move subapp to center of mainapp
            mainP = mainapp.UIFigure.Position;
            subP = app.UIFigure.Position;
            subP(1) = mainP(1) + 0.5*(mainP(3) - subP(3));
            subP(2) = mainP(2) + 0.5*(mainP(4) - subP(4));
            app.UIFigure.Position = subP;

            CheckButtonsExtractChannelsWaterApp(app);
        end

        % Button pushed function: ExtractInternalConstraintsButton
        function ExtractInternalConstraintsButtonPushed(app, event)
            ExtractInternalConstraints(app);
        end

        % Button pushed function: CreateLandWaterMaskButton
        function CreateLandWaterMaskButtonPushed(app, event)
            CreateLandWaterMask(app);
        end

        % Button pushed function: SwitchLandWaterMaskButton
        function SwitchLandWaterMaskButtonPushed(app, event)
            SwitchLandWaterMask(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            CloseChannelsWaterApp(app);
        end

        % Button pushed function: WriteADMESHinputfileButton
        function WriteADMESHinputfileButtonPushed(app, event)
            WriteADMESHinput(app);
        end

        % Value changed function: MinAreaEditField
        function MinAreaEditFieldValueChanged(app, event)
            MinAreaChangedExtractChannelsWaterApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout2 = uigridlayout(app.SettingsTab);
            app.MinAream2EditField_2Label = uilabel(app.GridLayout2);
            app.MinAream2EditField_2Label.Text = 'Min Area (m^2)';
            app.MinAreaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MinAreaEditField.ValueChangedFcn = createCallbackFcn(app, @MinAreaEditFieldValueChanged, true);
            app.BackgroundGridResolmEditFieldLabel = uilabel(app.GridLayout2);
            app.BackgroundGridResolmEditFieldLabel.Text = 'Background Grid Resol. (m)';
            app.BackgroundGridResolmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaxChannelWidthmEditFieldLabel = uilabel(app.GridLayout2);
            app.MaxChannelWidthmEditFieldLabel.Text = 'Max Channel Width (m)';
            app.MaxChannelWidthmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MinLengthmEditFieldLabel = uilabel(app.GridLayout2);
            app.MinLengthmEditFieldLabel.Text = 'Min Length (m)';
            app.MinLengthmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.AdvancedSettingsTab = uitab(app.TabGroup);
            app.AdvancedSettingsTab.Title = 'Advanced Settings';
            app.GridLayout2_2 = uigridlayout(app.AdvancedSettingsTab);
            app.NxDomainDecompEditFieldLabel = uilabel(app.GridLayout2_2);
            app.NxDomainDecompEditFieldLabel.Text = 'Nx Domain Decomp.';
            app.NxDomainDecompEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.NxDomainDecompEditField.Value = 1;
            app.NyDomainDecompEditFieldLabel = uilabel(app.GridLayout2_2);
            app.NyDomainDecompEditFieldLabel.Text = 'Ny Domain Decomp.';
            app.NyDomainDecompEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.NyDomainDecompEditField.Value = 1;
            app.PruningThetaEditFieldLabel = uilabel(app.GridLayout2_2);
            app.PruningThetaEditFieldLabel.Text = 'Pruning Theta';
            app.PruningThetaEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.PruningThetaEditField.ValueDisplayFormat = '%11.4g *pi';
            app.PruningThetaEditField.Value = 0.9;
            app.PruningRhoEditFieldLabel = uilabel(app.GridLayout2_2);
            app.PruningRhoEditFieldLabel.Text = 'Pruning Rho';
            app.PruningRhoEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.PruningRhoEditField.ValueDisplayFormat = '%11.4g * d_{cw}';
            app.PruningRhoEditField.Value = 2;
            app.CreateLandWaterMaskButton = uibutton(app.GridLayout, 'push');
            app.CreateLandWaterMaskButton.ButtonPushedFcn = createCallbackFcn(app, @CreateLandWaterMaskButtonPushed, true);
            app.CreateLandWaterMaskButton.Text = 'Create Land-Water Mask';
            app.SwitchLandWaterMaskButton = uibutton(app.GridLayout, 'push');
            app.SwitchLandWaterMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchLandWaterMaskButtonPushed, true);
            app.SwitchLandWaterMaskButton.Enable = 'off';
            app.SwitchLandWaterMaskButton.Text = 'Switch Land-Water Mask';
            app.WriteADMESHinputfileButton = uibutton(app.GridLayout, 'push');
            app.WriteADMESHinputfileButton.ButtonPushedFcn = createCallbackFcn(app, @WriteADMESHinputfileButtonPushed, true);
            app.WriteADMESHinputfileButton.Enable = 'off';
            app.WriteADMESHinputfileButton.Text = 'Write ADMESH input file';
            app.ExtractInternalConstraintsButton = uibutton(app.GridLayout, 'push');
            app.ExtractInternalConstraintsButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractInternalConstraintsButtonPushed, true);
            app.ExtractInternalConstraintsButton.Enable = 'off';
            app.ExtractInternalConstraintsButton.Text = 'Extract Internal Constraints';
            app.Step1Label = uilabel(app.GridLayout);
            app.Step1Label.Text = 'Step 1.';
            app.Step2Label = uilabel(app.GridLayout);
            app.Step2Label.Text = 'Step 2.';
            app.Step3Label = uilabel(app.GridLayout);
            app.Step3Label.Text = 'Step 3.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExtractChannelsWaterApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end