classdef ExtractChannelsLandApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Slider                         matlab.ui.control.Slider
        SliderLabel                    matlab.ui.control.Label
        MinDrainageAreaEditFieldLabel  matlab.ui.control.Label
        MinDrainageAreaEditField       matlab.ui.control.NumericEditField
        ExtractChannelButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
         % Description
    end
    
    properties (Access = public)
        MainApp
        PTS
        xyzFun
        ElevationDataFilename
        UIAxes
        ProgressBarButton
        TTC
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
            app.PTS = mainapp.PTS;
            app.UIAxes = mainapp.UIAxes;
            app.ProgressBarButton = mainapp.ProgressBarButton;
            app.ElevationDataFilename = mainapp.ElevationDataFilename;
            app.xyzFun = mainapp.xyzFun;
            app.MinDrainageAreaEditField.Value = mainapp.MinDrainageArea;

            % move subapp to center of mainapp
            mainP = mainapp.UIFigure.Position;
            subP = app.UIFigure.Position;
            subP(1) = mainP(1) + 0.5*(mainP(3) - subP(3));
            subP(2) = mainP(2) + 0.5*(mainP(4) - subP(4));
            app.UIFigure.Position = subP;

            CheckElevationDataFile(app);
        end

        % Button pushed function: ExtractChannelButton
        function ExtractChannelButtonPushed(app, event)
            ExtractChannelsLandSubdomain(app,'firstcall');
        end

        % Value changed function: MinDrainageAreaEditField
        function MinDrainageAreaEditFieldValueChanged(app, event)
            ExtractChannelsLandSubdomain(app,'update');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MainApp.MinDrainageArea = app.MinDrainageAreaEditField.Value;
            delete(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.MinDrainageAreaEditField.Value = value;
            ExtractChannelsLandSubdomain(app,'update');
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            value = event.Value;
            app.MinDrainageAreaEditField.Value = value;
            ExtractChannelsLandSubdomain(app,'update');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ExtractChannelButton = uibutton(app.GridLayout, 'push');
            app.ExtractChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractChannelButtonPushed, true);
            app.ExtractChannelButton.Text = 'Extract Channel';
            app.MinDrainageAreaEditField = uieditfield(app.GridLayout, 'numeric');
            app.MinDrainageAreaEditField.ValueChangedFcn = createCallbackFcn(app, @MinDrainageAreaEditFieldValueChanged, true);
            app.MinDrainageAreaEditField.Value = 100000;
            app.MinDrainageAreaEditFieldLabel = uilabel(app.GridLayout);
            app.MinDrainageAreaEditFieldLabel.Text = 'Min Drainage Area';
            app.SliderLabel = uilabel(app.GridLayout);
            app.SliderLabel.Enable = 'off';
            app.SliderLabel.Visible = 'off';
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.GridLayout);
            app.Slider.Limits = [0 1000000];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Enable = 'off';
            app.Slider.Visible = 'off';
            app.Slider.Value = 800000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExtractChannelsLandApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end